classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CryptoCardPanel                matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        TheEditField                   matlab.ui.control.EditField
        TheEditFieldLabel              matlab.ui.control.Label
        TransmittedDataEditField       matlab.ui.control.EditField
        TransmittedDataEditFieldLabel  matlab.ui.control.Label
        D9EditField                    matlab.ui.control.EditField
        D9EditFieldLabel               matlab.ui.control.Label
        D8EditField                    matlab.ui.control.EditField
        D8EditFieldLabel               matlab.ui.control.Label
        D7EditField                    matlab.ui.control.EditField
        D7EditFieldLabel               matlab.ui.control.Label
        D6EditField                    matlab.ui.control.EditField
        D6EditFieldLabel               matlab.ui.control.Label
        D5EditField                    matlab.ui.control.EditField
        D5EditFieldLabel               matlab.ui.control.Label
        D4EditField                    matlab.ui.control.EditField
        D4EditFieldLabel               matlab.ui.control.Label
        D3EditField                    matlab.ui.control.EditField
        D3EditFieldLabel               matlab.ui.control.Label
        D2EditField                    matlab.ui.control.EditField
        D2EditFieldLabel               matlab.ui.control.Label
        D1EditField                    matlab.ui.control.EditField
        D1EditFieldLabel               matlab.ui.control.Label
        DataPanel                      matlab.ui.container.Panel
        RUNButton                      matlab.ui.control.Button
        SpreadingIndexSpinner          matlab.ui.control.Spinner
        SpreadingIndexSpinnerLabel     matlab.ui.control.Label
        StaggeringIndex3Spinner        matlab.ui.control.Spinner
        StaggeringIndex3SpinnerLabel   matlab.ui.control.Label
        StaggeringIndex2Spinner        matlab.ui.control.Spinner
        StaggeringIndex2SpinnerLabel   matlab.ui.control.Label
        StaggeringIndex1Spinner        matlab.ui.control.Spinner
        StaggeringIndex1SpinnerLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
           % Set loop parameters
            loop = 9; % Number of D fields
            staggeringindexloop = 3; % Number of staggering index fields
            
            % Initialize arrays
            value = [];
            staggeringval = zeros(1, staggeringindexloop);
            binaryval = {};
            staggeringbinaryval = cell(1, staggeringindexloop);
            
            % Get values from edit fields and spinners
            for i = 1:loop
                varName = ['app.D', num2str(i), 'EditField.Value'];
                value{i} = eval(varName);
            end
            
            for i = 1:staggeringindexloop
                varName = ['app.StaggeringIndex', num2str(i), 'Spinner.Value'];
                staggeringval(i) = eval(varName);
            end
            
            Spreadingval = app.SpreadingIndexSpinner.Value;
            
            % Convert values to binary strings
            for i = 1:loop
                if isnan(str2num(value{i})) || ~isreal(str2num(value{i}))
                    errordlg(['Please Enter Value between 0 - 15 on D', num2str(i)], 'Incorrect Value')
                    return;
                elseif str2num(value{i}) < 0 || str2num(value{i}) > 15
                    errordlg(['Please Enter Value between 0 - 15 on D', num2str(i)], 'Incorrect Value')
                    return;
                else
                    stored = dec2bin(value{i}, 8);
                    sorted = stored(5:end);
                    binaryval{i} = ['0000' sorted];
                end
            end
            
            for i = 1:staggeringindexloop
                if isnan(staggeringval(i)) || ~isreal(staggeringval(i))
                    errordlg(['Please Enter Value between 0 - 15 on Staggering Index', num2str(i)], 'Incorrect Value')
                    return;
                elseif staggeringval(i) < 0 || staggeringval(i) > 15
                    errordlg(['Please Enter Value between 0 - 15 on Staggering Index', num2str(i)], 'Incorrect Value')
                    return;
                else
                    staggeringbinaryval{i} = dec2bin(staggeringval(i), 4);
                end
            end
            
            if isnan(Spreadingval) || ~isreal(Spreadingval)
                errordlg('Please Enter Value on Spreading Index between 0 - 255', 'Incorrect Value')
                return;
            elseif Spreadingval < 0 || Spreadingval > 255
                errordlg('Please Enter Value on Spreading Index between 0 - 255', 'Incorrect Value')
                return;
            else
                spreadingbinaryval = dec2bin(Spreadingval, 8);
            end
            
            % Concatenate binary strings
            binaryout = [binaryval{:}, staggeringbinaryval{:}, spreadingbinaryval];
            
            
            % Set the output
            app.TransmittedDataEditField.Value = binaryout;
            
            %%Transmitting the Data to UART
            message = serialport("COM7", 9600);
            writeline(message, binaryout);
            
            %%Recieving Data from UART
            recieveddata = readline(message);
            
            %Sending Value to Edit Field
            app.TheEditField.Value = recieveddata;

        end

        % Value changed function: D1EditField
        function D1EditFieldValueChanged(app, event)
            value = app.D1EditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CryptoCardPanel = uipanel(app.UIFigure);
            app.CryptoCardPanel.Title = 'Crypto Card';
            app.StaggeringIndex1SpinnerLabel = uilabel(app.CryptoCardPanel);
            app.StaggeringIndex1SpinnerLabel.Text = 'Staggering Index 1';
            app.StaggeringIndex1Spinner = uispinner(app.CryptoCardPanel);
            app.StaggeringIndex1Spinner.Limits = [0 15];
            app.StaggeringIndex2SpinnerLabel = uilabel(app.CryptoCardPanel);
            app.StaggeringIndex2SpinnerLabel.Text = 'Staggering Index 2';
            app.StaggeringIndex2Spinner = uispinner(app.CryptoCardPanel);
            app.StaggeringIndex2Spinner.Limits = [0 15];
            app.StaggeringIndex3SpinnerLabel = uilabel(app.CryptoCardPanel);
            app.StaggeringIndex3SpinnerLabel.Text = 'Staggering Index 3';
            app.StaggeringIndex3Spinner = uispinner(app.CryptoCardPanel);
            app.StaggeringIndex3Spinner.Limits = [0 15];
            app.SpreadingIndexSpinnerLabel = uilabel(app.CryptoCardPanel);
            app.SpreadingIndexSpinnerLabel.Text = 'Spreading Index';
            app.SpreadingIndexSpinner = uispinner(app.CryptoCardPanel);
            app.SpreadingIndexSpinner.Limits = [0 255];
            app.RUNButton = uibutton(app.CryptoCardPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.DataPanel = uipanel(app.CryptoCardPanel);
            app.DataPanel.Title = 'Data';
            app.D1EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D1EditField.ValueChangedFcn = createCallbackFcn(app, @D1EditFieldValueChanged, true);
            app.D2EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D3EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D4EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D4EditFieldLabel.Text = 'D4';
            app.D4EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D5EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D5EditFieldLabel.Text = 'D5';
            app.D5EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D6EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D6EditFieldLabel.Text = 'D6';
            app.D6EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D7EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D7EditFieldLabel.Text = 'D7';
            app.D7EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D8EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D8EditFieldLabel.Text = 'D8';
            app.D8EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.D9EditFieldLabel = uilabel(app.CryptoCardPanel);
            app.D9EditFieldLabel.Text = 'D9';
            app.D9EditField = uieditfield(app.CryptoCardPanel, 'text');
            app.TransmittedDataEditFieldLabel = uilabel(app.CryptoCardPanel);
            app.TransmittedDataEditFieldLabel.Text = 'Transmitted Data';
            app.TransmittedDataEditField = uieditfield(app.CryptoCardPanel, 'text');
            app.TheEditFieldLabel = uilabel(app.CryptoCardPanel);
            app.TheEditFieldLabel.Text = 'The';
            app.TheEditField = uieditfield(app.CryptoCardPanel, 'text');
            app.Image = uiimage(app.CryptoCardPanel);
            app.Image.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end